classdef DIG_COM_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        RecordButton          matlab.ui.control.Button
        OutputsTextArea_2     matlab.ui.control.TextArea
        RecordingLamp         matlab.ui.control.Lamp
        SourceCodesLabel      matlab.ui.control.Label
        ClearResetButton      matlab.ui.control.Button
        OutputsTextArea       matlab.ui.control.TextArea
        OutputsTextAreaLabel  matlab.ui.control.Label
        AudiotoBitsButton     matlab.ui.control.Button
        ImagetoBitsButton     matlab.ui.control.Button
        TexttoBitsButton      matlab.ui.control.Button
        RandomBitsButton      matlab.ui.control.Button
        CenterPanel           matlab.ui.container.Panel
        ReconstructButton     matlab.ui.control.Button
        BitErrorRateButton    matlab.ui.control.Button
        DemodulateButton      matlab.ui.control.Button
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
        AddAWGNButton         matlab.ui.control.Button
        SNRDBSlider           matlab.ui.control.Slider
        SNRDBSliderLabel      matlab.ui.control.Label
        QAMButton_2           matlab.ui.control.Button
        FSKButton_3           matlab.ui.control.Button
        QAMButton             matlab.ui.control.Button
        FSKButton             matlab.ui.control.Button
        ChannelCodingmaytakeawhileLabel  matlab.ui.control.Label
        BCH6351Button         matlab.ui.control.Button
        BCH12764Button        matlab.ui.control.Button
        HammingButton         matlab.ui.control.Button
        ChannelCodingLabel    matlab.ui.control.Label
        Button                matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        isRecording = false;
        recorder; 
        audioToBitsDone = false;
        isAudio2bitpressed = false;
        Fs=8000;%Sampling frequency in hertz
        ch=1;%Number of channels--2 options--1 (mono) or 2 (stereo)
        datatype='uint8';
        nbits=16;%8,16,or 24
        recordedAudioSignal = [];
        myVariable = false;
        source_code;
        encodedSource_stream;
        modulated_sig;
        SNRValue = [];
        time;
        noisySignal;
        padding;
        modulation_sig;
        fs_fck;
        fr_sep;
        no_samp;
        fs_qam;
        Original_lenght;
        src_code_format;
        gen_mtrx;
        decoded_bits_stream;
        length_qam;
        ber = [];
        src_code_method;
        gs_imag;
        
    end
    
    methods (Access = public)
        
        function CreateComp(app)
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPressFcn, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyReleaseFcn, true);
        end
        
        
        function RecordPushed(app,event)
            if app.isAudio2bitpressed
                % Toggle recording state
                app.isRecording = ~app.isRecording;

                if app.isRecording
                    % Start recording
                    
                    app.RecordingLamp.Color = [0, 1, 0]; % Set lamp color to green
                    app.OutputsTextArea_2.Value = 'Recording... Press Record to Stop';
                    app.recorder = audiorecorder(app.Fs, app.nbits, app.ch);
                    record(app.recorder);
                else
                    % Stop recording
                    app.RecordingLamp.Color = [1, 0, 0]; % Set lamp color to red
                    app.OutputsTextArea_2.Value = 'Recording stopped';
                    stop(app.recorder);
                    app.recordedAudioSignal = getaudiodata(app.recorder,app.datatype);
                    app.myVariable = true;
                    % Additional processing or saving if needed
                end
            else
                % Display a message or take appropriate action
                app.OutputsTextArea_2.Value = 'Press "Audio to Bits" button first.';
                app.recordedAudioSignal = [];
            end
            
        end
        
        function encodedBits = hammingCode74(app,inputBits)
            %
            % Ensure input length is a multiple of 4
            len = length(inputBits);
            app.Original_lenght = len;
            if rem(len, 4) ~= 0
                % Calculate the number of bits needed for padding
                paddingNeeded = 4 - rem(len, 4);
                app.padding = paddingNeeded;
        
                % Pad the input bits with zeros
                inputBits = [inputBits, zeros(1, paddingNeeded)];
                len = len + paddingNeeded;
            end
    
            % Generate the generator matrix
            pero = [1 1 0;1 0 1;0 1 1;1 1 1];
            G = [eye(4), pero];
            app.gen_mtrx = G;
    
            % Initialize encoded bits
            encodedBits = [];
            temp =0;
            % Encode 4-bit chunks using the generator matrix
            for i = 1:4:len
                dataChunk = inputBits(i:i+3);
                encodedChunk = mod(dataChunk * G, 2);
                %disp(encodedChunk);
                encodedBits = [encodedBits, encodedChunk];
                temp = temp+1;
            end
            %disp(temp);
        end

        function encodedBits = bchCode6351(app,inputBits)
        
            % Ensure input length is a multiple of 51
            len = length(inputBits);
            app.Original_lenght = len;
            if rem(len, 51) ~= 0
                % Calculate the number of bits needed for padding
                paddingNeeded = 51 - rem(len, 51);
                app.padding = paddingNeeded;
        
                % Pad the input bits with zeros
                inputBits = [inputBits, zeros(1, paddingNeeded)];
                len = len + paddingNeeded;
            end
    
            % Define parameters for the BCH code
            n = 63;
            k = 51;
            t = 6;
    
            % Initialize encoded bits
            encodedBits = [];
    
            % Encode 51-bit chunks using the BCH encoder
            %rani =0;
            %disp(inputBits);
            for i = 1:51:len
                dataChunk = inputBits(i:i+50);
                %disp(dataChunk)
                galDataChunk = gf(dataChunk);
                encodedChunk = bchenc(galDataChunk, n, k);
                %disp(encodedChunk.x);
                encodedBits = [encodedBits, encodedChunk.x'];
                %rani = rani+1;
            end
            %disp(rani);
            %disp(len);
            %disp(length(encodedBits));
            encodedBits = reshape(encodedBits, 1, []); % Reshape to row vector


        end


        function encodedBits = bchCode12764(app,inputBits)
            % Ensure input length is a multiple of 64
            len = length(inputBits);
            app.Original_lenght = len;
            if rem(len, 64) ~= 0
                paddingNeeded = 64 - rem(len, 64);
                app.padding = paddingNeeded;
                inputBits = [inputBits, zeros(1, paddingNeeded)];
                len = len + paddingNeeded;
            end
    
            % Define parameters for the BCH code
            n = 127;
            k = 64;
    
            % Initialize encoded bits
            encodedBits = [];
            
            % Encode 64-bit chunks using the BCH encoder
            for i = 1:64:len
                dataChunk = inputBits(i:i+63);
                galDataChunk = gf(dataChunk, 1);
                encodedChunk = bchenc(galDataChunk, n, k);
                encodedBits = [encodedBits, encodedChunk.x']; % Convert back to double array
            end
    
            % Ensure the final result remains in the double array format
            encodedBits = reshape(encodedBits, 1, []); % Reshape to row vector
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RandomBitsButton
        function RandomBitsButtonPushed(app, event)
            randomBits = randi([0, 1], 1, 1000);
            app.src_code_method = 0;

            app.source_code = randomBits;
            %disp(class(randomBits))
            app.OutputsTextArea.Value = num2str(randomBits);
        end

        % Button pushed function: TexttoBitsButton
        function TexttoBitsButtonPushed(app, event)
        app.src_code_method =1;
        [filename, filepath] = uigetfile('*.txt', 'Select a text file');

        if isequal(filename, 0)
            return;
        end

        fullFilePath = fullfile(filepath, filename);
        fileContent = fileread(fullFilePath);
        asciiCodes = double(fileContent);
        binaryStrings = dec2bin(asciiCodes, 8);
        %disp(binaryStrings)
        bits = reshape(binaryStrings', 1, []);
        bits = double(bits == '1');
        app.source_code = bits;
        fid = fopen("Text_Converted.txt", 'w');
        fwrite(fid, bits, 'char');
        fclose(fid);
        app.OutputsTextArea.Value = num2str(bits);
        end

        % Button pushed function: ImagetoBitsButton
        function ImagetoBitsButtonPushed(app, event)
        app.src_code_method =2;
        [filename, filepath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', 'Image Files'}, 'Select a grayscale image');
        if isequal(filename, 0)
            return;
        end
        fullFilePath = fullfile(filepath, filename);
        grayscaleImage = imread(fullFilePath);

        if size(grayscaleImage, 3) == 3
            grayscaleImage = rgb2gray(grayscaleImage);
        end
        app.gs_imag = grayscaleImage;
        image = double(grayscaleImage);

        normalizedImage = double(image) / 255;

        bits = reshape(dec2bin(normalizedImage * 255, 8)', 1, []);
        bits = double(bits == '1');
        app.source_code = bits;
        imshow(grayscaleImage, 'Parent', app.UIAxes);

        app.OutputsTextArea.Value = num2str(bits);
        fid = fopen("Converted_Image.txt", 'w');
        fwrite(fid, bits, 'ubit1');
        fclose(fid);
        end

        % Button pushed function: ClearResetButton
        function ClearResetButtonPushed(app, event)
            app.OutputsTextArea.Value = '';
            app.OutputsTextArea_2.Value = '';
            app.isRecording = false;
            app.audioToBitsDone = false;
            app.isAudio2bitpressed = false;
            app.recordedAudioSignal = [];
            app.myVariable = false;
            cla(app.UIAxes);
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            title(app.UIAxes,'');
            cla(app.UIAxes_2);
            xlabel(app.UIAxes_2, '');
            ylabel(app.UIAxes_2, '');
            title(app.UIAxes_2,'');
            cla(app.UIAxes_3);
            xlabel(app.UIAxes_3, '');
            ylabel(app.UIAxes_3, '');
            title(app.UIAxes_3,'');
            cla(app.UIAxes_4);
            xlabel(app.UIAxes_4, '');
            ylabel(app.UIAxes_4, '');
            title(app.UIAxes_4,'');
            app.SNRValue = 0;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             delete(app.UIFigure);
        end

        % Button pushed function: AudiotoBitsButton
        function AudiotoBitsButtonPushed(app, event)
            app.src_code_method =3;
            app.OutputsTextArea_2.Value = "Press Record to Start Recording";
            app.isAudio2bitpressed = ~app.isAudio2bitpressed;
            while ~app.myVariable
                pause(0.1);  % Adjust the pause duration as needed
            end
            
            audioSignal=app.recordedAudioSignal;
          
            audiowrite('test.wav',audioSignal,app.Fs);
            
            play(app.recorder);
            pause(length(audioSignal) / app.Fs);
            normalizedAudio = audioSignal / max(abs(audioSignal));
            %disp(audioSignal);
            %disp("stop");
            %bits = reshape(dec2bin(round(normalizedAudio * (2^15 - 1)), 16)', 1, []);
            bits = reshape(dec2bin( typecast( single(normalizedAudio(:)), 'uint8'), 8 ) - '0', 1, []);
            %bits = double(bits == '1');
            app.source_code = bits;
            app.OutputsTextArea.Value = num2str(bits);

            outputFileName = 'audio_bitstream.txt';
            fid = fopen(outputFileName, 'wt');
            fprintf(fid, '%s', bits);
            fclose(fid);

            t = (0:length(audioSignal)-1) / app.Fs;
            plot(app.UIAxes, t, audioSignal);
            xlabel(app.UIAxes, 'Time (s)');
            title(app.UIAxes, 'Audio Signal');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if app.isAudio2bitpressed
                RecordPushed(app,event);
            end
        end

        % Button pushed function: HammingButton
        function HammingButtonPushed(app, event)
            app.src_code_format = 0;
            % Get the source code from the public variable
            sourceCode = app.source_code;
    
            % Encode using the (7,4) Hamming code
            encodedSource = hammingCode74(app,sourceCode);
            
            % Perform further actions with the encoded source, like displaying or using it
            %disp('Encoded source using (7,4) Hamming code:');
            app.OutputsTextArea.Value = '';
            app.OutputsTextArea_2.Value = "Encoded source using (7,4) Hamming code :";
            %disp(encodedSource);
            app.OutputsTextArea.Value = num2str(encodedSource);
    
            % You can assign it back to the app or use it in your app as needed
            app.encodedSource_stream = encodedSource; % Assign it to an app property if required
        end

        % Button pushed function: BCH6351Button
        function BCH6351ButtonPushed(app, event)
            % Get the source code from the public variable
            app.src_code_format = 1;
            sourceCode = app.source_code;
            % Encode using the (7,4) Hamming code
            encodedSource = bchCode6351(app,sourceCode);
            % Perform further actions with the encoded source, like displaying or using it
            %disp('Encoded source using (7,4) Hamming code:');
            app.OutputsTextArea.Value = '';
            app.OutputsTextArea_2.Value = "Encoded source using (63,51) BCH code :";
            %disp(encodedSource);
            app.OutputsTextArea.Value = num2str(encodedSource);
    
            % You can assign it back to the app or use it in your app as needed
            app.encodedSource_stream = encodedSource; % Assign it to an app property if required
        end

        % Button pushed function: BCH12764Button
        function BCH12764ButtonPushed(app, event)
            % Get the source code from the public variable
            app.src_code_format = 2;
            sourceCode = app.source_code;
    
            % Encode using the (7,4) Hamming code
            encodedSource = bchCode12764(app,sourceCode);
            % Perform further actions with the encoded source, like displaying or using it
            %disp('Encoded source using (7,4) Hamming code:');
            app.OutputsTextArea.Value = '';
            app.OutputsTextArea_2.Value = "Encoded source using (127,64) BCH code :";
            %disp(encodedSource);
            app.OutputsTextArea.Value = num2str(encodedSource);
    
            % You can assign it back to the app or use it in your app as needed
            app.encodedSource_stream = encodedSource; % Assign it to an app property if required
        end

        % Button pushed function: FSKButton
        function FSKButtonPushed(app, event)
            app.modulation_sig = 0;
            % Parameters
            M = 16; % Number of FSK levels
            Fs_fsk = 500; % Sampling frequency
            freq_sep = 30; % Frequency separation between symbols
            nsamp = 50; % Number of samples per symbol
            app.fs_fck = Fs_fsk;
            app.fr_sep = freq_sep;
            app.no_samp = nsamp;

            % Convert bits to symbols
            symbols = app.encodedSource_stream; % Adjust indices to 1 and 2

            % Modulate using fskmod
            fskSignal = fskmod(symbols, M, freq_sep, nsamp, Fs_fsk);

            app.modulated_sig = fskSignal;

            % Time vector
            t = (0:length(fskSignal)-1)/Fs_fsk;
            app.time = t;
    
            % Plot FSK signal (Time Domain) in UIAxes1
            plot(app.UIAxes_2, t, fskSignal);
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            xticks(app.UIAxes_2);
            yticks(app.UIAxes_2);
            title(app.UIAxes_2, '16-FSK Modulated Signal (Time Domain)');
    
            % Calculate and plot Power Spectral Density (PSD) in UIAxes2
            NFFT = 2^nextpow2(length(fskSignal)); % Next power of 2 from length of signal
            Y = fft(fskSignal, NFFT)/length(fskSignal);
            f = Fs_fsk/2*linspace(0, 1, NFFT/2+1);
    
            plot(app.UIAxes_3, f, 2*abs(Y(1:NFFT/2+1)));
            title(app.UIAxes_3, 'Power Spectral Density');
            xlabel(app.UIAxes_3, 'Frequency (Hz)');
            ylabel(app.UIAxes_3, 'Magnitude');

            % Plot constellation diagram in the UI axes
            scatter(app.UIAxes_4, real(fskSignal), imag(fskSignal), 'o');
            xlabel(app.UIAxes_4, 'I (In-Phase)');
            ylabel(app.UIAxes_4, 'Q (Quadrature)');
            title(app.UIAxes_4, '16-FSK Constellation Diagram');
            grid(app.UIAxes_4, 'on');
        end

        % Button pushed function: FSKButton_3
        function FSKButton_3Pushed(app, event)
            app.modulation_sig = 1;
            % Parameters
            M = 64; % Number of FSK levels
            Fs_fsk = 500; % Sampling frequency
            freq_sep = 6; % Frequency separation between symbols
            nsamp = 50; % Number of samples per symbol
            app.fs_fck = Fs_fsk;
            app.fr_sep = freq_sep;
            app.no_samp = nsamp;


            % Convert bits to symbols
            symbols = app.encodedSource_stream; % Adjust indices to 1 and 2

            % Modulate using fskmod
            fskSignal = fskmod(symbols, M, freq_sep, nsamp, Fs_fsk);
            app.modulated_sig = fskSignal;

            % Time vector
            t = (0:length(fskSignal)-1)/Fs_fsk;
            app.time = t;
    
            % Plot FSK signal (Time Domain) in UIAxes1
            plot(app.UIAxes_2, t, fskSignal);
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            xticks(app.UIAxes_2);
            yticks(app.UIAxes_2);
            title(app.UIAxes_2, '64-FSK Modulated Signal (Time Domain)');
    
            % Calculate and plot Power Spectral Density (PSD) in UIAxes2
            NFFT = 2^nextpow2(length(fskSignal)); % Next power of 2 from length of signal
            Y = fft(fskSignal, NFFT)/length(fskSignal);
            f = Fs_fsk/2*linspace(0, 1, NFFT/2+1);
    
            plot(app.UIAxes_3, f, 2*abs(Y(1:NFFT/2+1)));
            title(app.UIAxes_3, 'Power Spectral Density');
            xlabel(app.UIAxes_3, 'Frequency (Hz)');
            ylabel(app.UIAxes_3, 'Magnitude');

            % Plot constellation diagram in the UI axes
            scatter(app.UIAxes_4, real(fskSignal), imag(fskSignal), 'o');
            xlabel(app.UIAxes_4, 'I (In-Phase)');
            ylabel(app.UIAxes_4, 'Q (Quadrature)');
            title(app.UIAxes_4, '16-FSK Constellation Diagram');
            grid(app.UIAxes_4, 'on');
        end

        % Button pushed function: QAMButton
        function QAMButtonPushed(app, event)
            app.modulation_sig = 2;
            % Parameters for 16-QAM modulation
            M_16qam = 16;          % Number of QAM levels for 16-QAM
            Fs_16qam = 1000;        % Sampling frequency for 16-QAM
            nsamp_16qam = 50;      % Number of samples per symbol for 16-QAM
            app.fs_qam = Fs_16qam;

            % Convert bits to symbols for 16-QAM (adjust indices if necessary)
            symbols_16qam = app.encodedSource_stream;
            app.length_qam = length(symbols_16qam);

            % Calculate the number of bits to zero-pad
            numBits = 4 - rem(length(symbols_16qam), 4);

            % Zero-pad the binary data
            symbols_16qam = [symbols_16qam, zeros(1, numBits)]; % Pad with zeros

            % Reshape binary data into groups of 4 bits
            binaryDataGroups = reshape(symbols_16qam, 4, [])';

            % Convert binary groups to decimal values
            decimalValues = bi2de(binaryDataGroups, 'left-msb');

            % Modulate using qammod for 16-QAM
            qamSignal_16 = qammod(decimalValues, M_16qam,PlotConstellation=true);
            app.modulated_sig = qamSignal_16;
    
            % Time vector for 16-QAM
            t_16qam = (0:length(qamSignal_16)-1)/Fs_16qam;
            app.time = t_16qam;

            % Plot 16-QAM signal (Time Domain) in UIAxes2
            plot(app.UIAxes_2, t_16qam, real(qamSignal_16));
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            xticks(app.UIAxes_2);
            yticks(app.UIAxes_2);
            title(app.UIAxes_2, '16-QAM Modulated Signal (Time Domain)');

            % Calculate and plot Power Spectral Density (PSD) for 16-QAM in UIAxes3
            %NFFT_16qam = 2^nextpow2(length(qamSignal_16)); % Next power of 2 from length of signal
            %Y_16qam = fft(qamSignal_16, NFFT_16qam)/length(qamSignal_16);
            %f_16qam = Fs_16qam/2*linspace(0, 1, NFFT_16qam/2+1);
            N = length(qamSignal_16);

            psd = 10*log10(abs(fft(qamSignal_16, N)).^2/N);
            freq = linspace(-Fs_16qam/2, Fs_16qam/2, N);  % Frequency axis

            plot(app.UIAxes_3, freq, fftshift(psd));
            title(app.UIAxes_3, 'Power Spectral Density (16-QAM)');
            xlabel(app.UIAxes_3, 'Frequency (Hz)');
            ylabel(app.UIAxes_3, 'Magnitude');

            % Plot Constellation for 16-QAM in UIAxes4
%             scatter(app.UIAxes_4, real(qamSignal_16), imag(qamSignal_16), 'filled');
%             xlabel(app.UIAxes_4, 'In-Phase');
%             ylabel(app.UIAxes_4, 'Quadrature');
%             title(app.UIAxes_4, 'Constellation Diagram (16-QAM)');


        end

        % Button pushed function: QAMButton_2
        function QAMButton_2Pushed(app, event)
            app.modulation_sig = 3;
            % Parameters for 64-QAM modulation
            M_64qam = 64;          % Number of QAM levels for 64-QAM
            Fs_64qam = 1000;        % Sampling frequency for 64-QAM
            nsamp_64qam = 50;      % Number of samples per symbol for 64-QAM
            app.fs_qam = Fs_64qam;

            % Convert bits to symbols for 64-QAM (adjust indices if necessary)
            symbols_64qam = app.encodedSource_stream;
            app.length_qam = length(symbols_64qam);

            % Calculate the number of bits to zero-pad
            numBits = 6 - rem(length(symbols_64qam), 6);

            % Zero-pad the binary data
            symbols_64qam = [symbols_64qam, zeros(1, numBits)]; % Pad with zeros

            % Reshape binary data into groups of 6 bits
            binaryDataGroups = reshape(symbols_64qam, 6, [])';

            % Convert binary groups to decimal values
            decimalValues = bi2de(binaryDataGroups, 'left-msb');

            % Modulate using qammod for 64-QAM
            qamSignal_64 = qammod(decimalValues, M_64qam,PlotConstellation=true);
            app.modulated_sig = qamSignal_64;
    
            % Time vector for 64-QAM
            t_64qam = (0:length(qamSignal_64)-1)/Fs_64qam;
            app.time = t_64qam;

            % Plot 16-QAM signal (Time Domain) in UIAxes2
            plot(app.UIAxes_2, t_64qam, real(qamSignal_64));
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            xticks(app.UIAxes_2);
            yticks(app.UIAxes_2);
            title(app.UIAxes_2, '64-QAM Modulated Signal (Time Domain)');

            % Calculate and plot Power Spectral Density (PSD) for 16-QAM in UIAxes3
            %NFFT_16qam = 2^nextpow2(length(qamSignal_16)); % Next power of 2 from length of signal
            %Y_16qam = fft(qamSignal_16, NFFT_16qam)/length(qamSignal_16);
            %f_16qam = Fs_16qam/2*linspace(0, 1, NFFT_16qam/2+1);
            N = length(qamSignal_64);

            psd = 10*log10(abs(fft(qamSignal_64, N)).^2/N);
            freq = linspace(-Fs_64qam/2, Fs_64qam/2, N);  % Frequency axis

            plot(app.UIAxes_3, freq, fftshift(psd));
            title(app.UIAxes_3, 'Power Spectral Density (64-QAM)');
            xlabel(app.UIAxes_3, 'Frequency (Hz)');
            ylabel(app.UIAxes_3, 'Magnitude');

            % Plot Constellation for 64-QAM in UIAxes4
%             scatter(app.UIAxes_4, real(qamSignal_64), imag(qamSignal_64), 'filled');
%             xlabel(app.UIAxes_4, 'In-Phase');
%             ylabel(app.UIAxes_4, 'Quadrature');
%             title(app.UIAxes_4, 'Constellation Diagram (64-QAM)');
        end

        % Value changed function: SNRDBSlider
        function SNRDBSliderValueChanged(app, event)
            app.SNRValue = [app.SNRValue,app.SNRDBSlider.Value];
            
        end

        % Button pushed function: AddAWGNButton
        function AddAWGNButtonPushed(app, event)
            % Check if the modulated signal and SNR value are available
            % Add AWGN noise to the modulated signal with the stored SNR value
            %disp(app.SNRValue);
            noisySignal = awgn(app.modulated_sig, app.SNRDBSlider.Value  , 'measured');

            %disp(noisySignal);
        
            % Store or use the noisy signal (plot, further processing, etc.)
            app.noisySignal = noisySignal; % Store the noisy signal in the app data
%             noise = randn(1, length(app.modulated_sig));
%             signal_power = mean(abs(app.modulated_sig).^2);
%             noise_power = signal_power;
%             noisySignal = app.modulated_sig + sqrt(noise_power) * noise;
%             app.noisySignal = noisySignal;


            % Update UI elements or perform actions with the noisy signal
            % For example, plot the noisy signal
            plot(app.UIAxes,app.time, noisySignal); % Adjust 'time' based on your signal
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Noisy Modulated Signal');
        end

        % Button pushed function: DemodulateButton
        function DemodulateButtonPushed(app, event)
            if app.modulation_sig == 0
                M = 16; % Number of FSK levels
                
                %disp(app.noisySignal);
                demodulatedSymbols = fskdemod(app.noisySignal, M, app.fr_sep, app.no_samp, app.fs_fck); % Assuming this is demodulated signal
                %disp(sum(demodulatedSymbols));
                % Convert symbols to bits
%                 numBitsPerSymbol = log2(M); % Number of bits represented by each symbol
%                 demodulatedBits = de2bit(demodulatedSymbols, numBitsPerSymbol, 'left-msb');
                if app.src_code_format==0
                    %code
                    % Define the parameters for the (7,4) Hamming code
                    n = 7; % Total length of the received codeword
                    k = 4; % Number of data bits
                    r = n - k; % Number of parity bits

                    % Ensure the received signal length is a multiple of n
                    len = length(demodulatedSymbols);
                    %disp(len)

                    % Reshape the received signal into codewords
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    H = [1 0 1 0 1 0 1;0 1 1 0 0 1 1;0 0 0 1 1 1 1];
                    %disp(received_codewords);
                    decoded_bits = [];
                    for i = 1:num_codewords
                        % Extract a single codeword
                        codeword = received_codewords(i, :);
                        syndrome = mod(codeword * H', 2);
                        %disp(sum(syndrome))
                        if sum(syndrome) == 0
                            % No error, return received word as decoded message
                            decodedMessage = codeword(1:4);
                        else
                            errorPosition = bi2de(syndrome, 'left-msb');
                            codeword(errorPosition) = mod(codeword(errorPosition) + 1, 2);

                            % Return corrected message
                            decodedMessage = codeword(1:4);
                        end
                        
                        decoded_bits = [decoded_bits,decodedMessage];                        
                    end
                    %disp(decoded_bits);
                    app.decoded_bits_stream = decoded_bits;


                elseif app.src_code_format==1
                    n = 63;
                    k = 51;
                    len = length(demodulatedSymbols);
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    decoded_bits = [];
                    for i = 1:num_codewords
                        codeword = received_codewords(i,:);
                        decodedMessage = bchdec(gf(codeword),n,k);
                        decoded_bits = [decoded_bits,decodedMessage.x];
                    end
                    app.decoded_bits_stream = decoded_bits(1:app.Original_lenght);


                elseif app.src_code_format==2
                    n = 127;
                    k = 64;
                    len = length(demodulatedSymbols);
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    decoded_bits = [];
                    for i = 1:num_codewords
                        codeword = received_codewords(i,:);
                        decodedMessage = bchdec(gf(codeword),n,k);
                        decoded_bits = [decoded_bits,decodedMessage.x];
                    end
                    app.decoded_bits_stream = decoded_bits(1:app.Original_lenght);


                end
            elseif app.modulation_sig == 1
                %code
                M = 64; % Number of FSK levels
                
                %disp(app.noisySignal);
                demodulatedSymbols = fskdemod(app.noisySignal, M, app.fr_sep, app.no_samp, app.fs_fck); % Assuming this is demodulated signal


                if app.src_code_format==0
                     %code
                    % Define the parameters for the (7,4) Hamming code
                    n = 7; % Total length of the received codeword
                    k = 4; % Number of data bits
                    r = n - k; % Number of parity bits

                    % Ensure the received signal length is a multiple of n
                    len = length(demodulatedSymbols);
                    %disp(len)

                    % Reshape the received signal into codewords
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    H = [1 0 1 0 1 0 1;0 1 1 0 0 1 1;0 0 0 1 1 1 1];
                    %disp(received_codewords);
                    decoded_bits = [];
                    for i = 1:num_codewords
                        % Extract a single codeword
                        codeword = received_codewords(i, :);
                        syndrome = mod(codeword * H', 2);
                        %disp(sum(syndrome))
                        if sum(syndrome) == 0
                            % No error, return received word as decoded message
                            decodedMessage = codeword(1:4);
                        else
                            errorPosition = bi2de(syndrome, 'left-msb');
                            codeword(errorPosition) = mod(codeword(errorPosition) + 1, 2);

                            % Return corrected message
                            decodedMessage = codeword(1:4);
                        end
                        
                        decoded_bits = [decoded_bits,decodedMessage];                        
                    end
                    %disp(decoded_bits);
                    app.decoded_bits_stream = decoded_bits;


                elseif app.src_code_format==1
                    %code
                    n = 63;
                    k = 51;
                    len = length(demodulatedSymbols);
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    decoded_bits = [];
                    for i = 1:num_codewords
                        codeword = received_codewords(i,:);
                        decodedMessage = bchdec(gf(codeword),n,k);
                        decoded_bits = [decoded_bits,decodedMessage.x];
                    end
                    app.decoded_bits_stream = decoded_bits(1:app.Original_lenght);


                elseif app.src_code_format==2
                    %code
                    n = 127;
                    k = 64;
                    len = length(demodulatedSymbols);
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    decoded_bits = [];
                    for i = 1:num_codewords
                        codeword = received_codewords(i,:);
                        decodedMessage = bchdec(gf(codeword),n,k);
                        decoded_bits = [decoded_bits,decodedMessage.x];
                    end
                    app.decoded_bits_stream = decoded_bits(1:app.Original_lenght);


                end


            elseif app.modulation_sig == 2
                %code
                M_qam = 16;
                demodulatedSymbols = qamdemod(app.noisySignal, M_qam);
                % Convert symbols to binary groups
                binaryDataGroups_received = de2bi(demodulatedSymbols, 4, 'left-msb');

                % Reshape binary groups to a linear array
                binaryData_received = reshape(binaryDataGroups_received', 1, []);

                % Remove padded zeros
                demodulatedSymbols = binaryData_received(1:app.length_qam); % Assuming app.length_qam stores the original length


                if app.src_code_format==0
                    %code
                     %code
                    % Define the parameters for the (7,4) Hamming code
                    n = 7; % Total length of the received codeword
                    k = 4; % Number of data bits
                    r = n - k; % Number of parity bits

                    % Ensure the received signal length is a multiple of n
                    len = length(demodulatedSymbols);
                    %disp(len)

                    % Reshape the received signal into codewords
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    H = [1 0 1 0 1 0 1;0 1 1 0 0 1 1;0 0 0 1 1 1 1];
                    %disp(received_codewords);
                    decoded_bits = [];
                    for i = 1:num_codewords
                        % Extract a single codeword
                        codeword = received_codewords(i, :);
                        syndrome = mod(codeword * H', 2);
                        %disp(sum(syndrome))
                        if sum(syndrome) == 0
                            % No error, return received word as decoded message
                            decodedMessage = codeword(1:4);
                        else
                            errorPosition = bi2de(syndrome, 'left-msb') ;
                            %disp(errorPosition);
                            codeword(errorPosition) = mod(codeword(errorPosition) + 1, 2);

                            % Return corrected message
                            decodedMessage = codeword(1:4);
                        end
                        
                        decoded_bits = [decoded_bits,decodedMessage];                        
                    end
                    %disp(decoded_bits);
                    app.decoded_bits_stream = decoded_bits;


                elseif app.src_code_format==1
                    %code
                    %code
                    n = 63;
                    k = 51;
                    len = length(demodulatedSymbols);
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    decoded_bits = [];
                    for i = 1:num_codewords
                        codeword = received_codewords(i,:);
                        decodedMessage = bchdec(gf(codeword),n,k);
                        decoded_bits = [decoded_bits,decodedMessage.x];
                    end
                    app.decoded_bits_stream = decoded_bits(1:app.Original_lenght);


                elseif app.src_code_format==2
                    %code
                    n = 127;
                    k = 64;
                    len = length(demodulatedSymbols);
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    decoded_bits = [];
                    for i = 1:num_codewords
                        codeword = received_codewords(i,:);
                        decodedMessage = bchdec(gf(codeword),n,k);
                        decoded_bits = [decoded_bits,decodedMessage.x];
                    end
                    app.decoded_bits_stream = decoded_bits(1:app.Original_lenght);


                end
                %disp(app.decoded_bits_stream)


            elseif app.modulation_sig == 3
                %code
                M_qam = 64;
                demodulatedSymbols = qamdemod(app.noisySignal, M_qam);
                % Convert symbols to binary groups
                binaryDataGroups_received = de2bi(demodulatedSymbols, 6, 'left-msb');

                % Reshape binary groups to a linear array
                binaryData_received = reshape(binaryDataGroups_received', 1, []);

                % Remove padded zeros
                demodulatedSymbols = binaryData_received(1:app.length_qam); % Assuming app.length_qam stores the original length


                if app.src_code_format==0
                    %code
                     %code
                    % Define the parameters for the (7,4) Hamming code
                    n = 7; % Total length of the received codeword
                    k = 4; % Number of data bits
                    r = n - k; % Number of parity bits

                    % Ensure the received signal length is a multiple of n
                    len = length(demodulatedSymbols);
                    %disp(len)

                    % Reshape the received signal into codewords
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    H = [1 0 1 0 1 0 1;0 1 1 0 0 1 1;0 0 0 1 1 1 1];
                    %disp(received_codewords);
                    decoded_bits = [];
                    for i = 1:num_codewords
                        % Extract a single codeword
                        codeword = received_codewords(i, :);
                        syndrome = mod(codeword * H', 2);
                        %disp(sum(syndrome))
                        if sum(syndrome) == 0
                            % No error, return received word as decoded message
                            decodedMessage = codeword(1:4);
                        else
                            errorPosition = bi2de(syndrome, 'left-msb');
                            codeword(errorPosition) = mod(codeword(errorPosition) + 1, 2);

                            % Return corrected message
                            decodedMessage = codeword(1:4);
                        end
                        
                        decoded_bits = [decoded_bits,decodedMessage];                        
                    end
                    %disp(decoded_bits);
                    app.decoded_bits_stream = decoded_bits;


                elseif app.src_code_format==1
                    %code
                    %code
                    n = 63;
                    k = 51;
                    len = length(demodulatedSymbols);
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    decoded_bits = [];
                    for i = 1:num_codewords
                        codeword = received_codewords(i,:);
                        decodedMessage = bchdec(gf(codeword),n,k);
                        decoded_bits = [decoded_bits,decodedMessage.x];
                    end
                    app.decoded_bits_stream = decoded_bits(1:app.Original_lenght);


                elseif app.src_code_format==2
                     %code
                    n = 127;
                    k = 64;
                    len = length(demodulatedSymbols);
                    num_codewords = len / n;
                    received_codewords = reshape(demodulatedSymbols, n, num_codewords)';
                    decoded_bits = [];
                    for i = 1:num_codewords
                        codeword = received_codewords(i,:);
                        decodedMessage = bchdec(gf(codeword),n,k);
                        decoded_bits = [decoded_bits,decodedMessage.x];
                    end
                    app.decoded_bits_stream = decoded_bits(1:app.Original_lenght);


                end


            end
            %disp(app.decoded_bits_stream);
            app.ber = [app.ber, biterr(app.source_code,app.decoded_bits_stream)];

           
        end

        % Button pushed function: BitErrorRateButton
        function BitErrorRateButtonPushed(app, event)
            if app.modulation_sig==2
                M = 16;
                EbNo_dB = 0:4:60;       % Eb/N0 values in dB
                EbNo = 10.^(EbNo_dB/10);% Eb/N0 values in linear scale
                %ber = zeros(size(EbNo));% Initialize BER array
%                 for i = 1:length(EbNo)                
%                     % Calculate bit errors
%                     %noisysignal = awgn(double(app.decoded_bits_stream), EbNo_dB(i), 'measured');
%                     ber(i) = biterr(app.source_code,app.decoded_bits_stream);
%                 end
                
                %disp(app.ber);
                bit_err = app.ber/length(app.source_code);
                %disp(bit_err);
%                 small_value = 1e-100; % Define a small non-zero value
%                 bit_err(bit_err == 0) = small_value;
                theoreticalBer = berawgn(app.SNRValue, 'qam', M);
                small_value = theoreticalBer(2);
                for i=1:length(bit_err(bit_err == 0))
                    small_value = small_value*(10^-10); % Define a small non-zero value
                    bit_err(bit_err == 0) = small_value;
                end
                %disp(bit_err);
                semilogy(app.UIAxes_2, app.SNRValue, bit_err, 'o-', 'LineWidth', 2);
                hold(app.UIAxes_2, 'on');
                semilogy(app.UIAxes_2, app.SNRValue, theoreticalBer, 'r--', 'LineWidth', 1.5);
                hold(app.UIAxes_2, 'off');
                grid(app.UIAxes_2, 'on');
                xlabel(app.UIAxes_2, 'Eb/No (dB)');
                ylabel(app.UIAxes_2, 'Bit Error Rate (BER)');
                legend(app.UIAxes_2, 'Simulated BER', 'Theoretical BER');
                title(app.UIAxes_2, 'BER vs Eb/No for 16-QAM Modulation');
            elseif app.modulation_sig ==3
                M = 64;
                EbNo_dB = 0:4:60;       % Eb/N0 values in dB
                EbNo = 10.^(EbNo_dB/10);% Eb/N0 values in linear scale
%                 ber = zeros(size(EbNo));% Initialize BER array
%                 for i = 1:length(EbNo)                
%                     % Calculate bit errors
%                     %noisysignal = awgn(double(app.decoded_bits_stream), EbNo_dB(i), 'measured');
%                     ber(i) = biterr(app.source_code,app.decoded_bits_stream);
%                 end
                bit_err = app.ber/length(app.source_code);
                %disp(bit_err);
                theoreticalBer = berawgn(app.SNRValue, 'qam', M);
                small_value = theoreticalBer(2);
                for i=1:length(bit_err(bit_err == 0))
                    small_value = small_value*(10^-10); % Define a small non-zero value
                    bit_err(bit_err == 0) = small_value;
                end
                semilogy(app.UIAxes_2, app.SNRValue, bit_err, 'o-', 'LineWidth', 2);
                hold(app.UIAxes_2, 'on');
                semilogy(app.UIAxes_2, app.SNRValue, theoreticalBer, 'r--', 'LineWidth', 1.5);
                hold(app.UIAxes_2, 'off');
                grid(app.UIAxes_2, 'on');
                xlabel(app.UIAxes_2, 'Eb/No (dB)');
                ylabel(app.UIAxes_2, 'Bit Error Rate (BER)');
                legend(app.UIAxes_2, 'Simulated BER', 'Theoretical BER');
                title(app.UIAxes_2, 'BER vs Eb/No for 64-QAM Modulation');
            elseif app.modulation_sig==0
                M = 16;                         % Number of FSK levels
                EbNo_dB = 0:4:60;               % Range of Eb/N0 values in dB
                EbNo = 10.^(EbNo_dB/10);        % Convert Eb/N0 values to linear scale
                %numBits = 1e5;                  % Number of bits for simulation

%                 ber = zeros(size(EbNo));        % Initialize BER array
% 
%                 for i = 1:length(EbNo)
%                     % Calculate bit errors
%                     ber(i) = biterr(app.source_code, app.decoded_bits_stream);
%                 end
%                 disp(ber);
                bit_err = app.ber/length(app.source_code);
                % Calculate theoretical BER
                theoreticalBer = berawgn(app.SNRValue, 'fsk', M,"coherent");
                small_value = theoreticalBer(2);
                for i=1:length(bit_err(bit_err == 0))
                    small_value = small_value*(10^-10); % Define a small non-zero value
                    bit_err(bit_err == 0) = small_value;
                end
                % Plot BER vs Eb/No
                semilogy(app.UIAxes_2, app.SNRValue, bit_err, 'o-', 'LineWidth', 2);
                hold(app.UIAxes_2, 'on');
                semilogy(app.UIAxes_2, app.SNRValue, theoreticalBer, 'r--', 'LineWidth', 1.5);
                hold(app.UIAxes_2, 'off');
                grid(app.UIAxes_2, 'on');
                xlabel(app.UIAxes_2, 'Eb/No (dB)');
                ylabel(app.UIAxes_2, 'Bit Error Rate (BER)');
                legend(app.UIAxes_2, 'Simulated BER', 'Theoretical BER');
                title(app.UIAxes_2, 'BER vs Eb/No for 16-FSK Modulation');
            elseif app.modulation_sig==1
                M = 64;                         % Number of FSK levels
                EbNo_dB = 0:4:60;               % Range of Eb/N0 values in dB
                EbNo = 10.^(EbNo_dB/10);        % Convert Eb/N0 values to linear scale
                %numBits = 1e5;                  % Number of bits for simulation

%                 ber = zeros(size(EbNo));        % Initialize BER array
% 
%                 for i = 1:length(EbNo)
%                     % Calculate bit errors
%                     ber(i) = biterr(app.source_code, app.decoded_bits_stream);
%                 end
%                 disp(ber); 
                bit_err = app.ber/length(app.source_code);
                % Calculate theoretical BER
                theoreticalBer = berawgn(app.SNRValue, 'fsk', M,"coherent");
                small_value = theoreticalBer(2);
                for i=1:length(bit_err(bit_err == 0))
                    small_value = small_value*(10^-10); % Define a small non-zero value
                    bit_err(bit_err == 0) = small_value;
                end
                % Plot BER vs Eb/No
                semilogy(app.UIAxes_2, app.SNRValue, bit_err, 'o-', 'LineWidth', 2);
                hold(app.UIAxes_2, 'on');
                semilogy(app.UIAxes_2, app.SNRValue, theoreticalBer, 'r--', 'LineWidth', 1.5);
                hold(app.UIAxes_2, 'off');
                grid(app.UIAxes_2, 'on');
                xlabel(app.UIAxes_2, 'Eb/No (dB)');
                ylabel(app.UIAxes_2, 'Bit Error Rate (BER)');
                legend(app.UIAxes_2, 'Simulated BER', 'Theoretical BER');
                title(app.UIAxes_2, 'BER vs Eb/No for 64-FSK Modulation');


             end


        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            if app.src_code_method==0
                app.OutputsTextArea.Value = num2str(app.decoded_bits_stream);
                app.OutputsTextArea_2.Value = "The Reconstructed source codes are : ";
            elseif app.src_code_method==1
                % Convert bits back to ASCII codes (assuming 8-bit ASCII encoding)
                num_chars = floor(length(app.decoded_bits_stream) / 8);
                bits_matrix = reshape(app.decoded_bits_stream(1:num_chars * 8), 8, num_chars).';
                decimal_values = bi2de(bits_matrix, 'left-msb');

                % Convert ASCII codes to characters
                reconstructed_text = char(decimal_values);
                reconstructed_text = reshape(reconstructed_text.', 1, []);
                app.OutputsTextArea.Value = reconstructed_text;

                %disp('Reconstructed Text:');
                %disp(reconstructed_text);
            elseif app.src_code_method==2
                % Calculate the number of pixels in the image
                num_pixels = numel(app.decoded_bits_stream) / 8;

                % Reshape the bits into the original image size
                bits_matrix = reshape(app.decoded_bits_stream(1:num_pixels * 8), 8, num_pixels).';

                % Convert the bits back to decimal values
                decimal_values = bi2de(bits_matrix, 'left-msb');

                reconstructed_values = uint8((255 / (2^8 - 1)) * decimal_values);

                % Reshape the decimal values into the original image dimensions
                reconstructed_image = reshape(reconstructed_values, size(app.gs_imag));

                % Display the reconstructed image
                %imshow(uint8(reconstructed_image), 'Parent', app.UIAxes);
                cla(app.UIAxes);
                xlabel(app.UIAxes, '');
                ylabel(app.UIAxes, '');
                imshow(reconstructed_image, 'Parent', app.UIAxes);
                title(app.UIAxes,'Reconstructed Image');
            elseif app.src_code_method==3

                % Reshape the bits into a matrix
                num_samples = length(app.decoded_bits_stream) / 8;
                %app.decoded_bits_stream = app.decoded_bits_stream * abs(max(app.recordedAudioSignal));
                bits_matrix = reshape(app.decoded_bits_stream(1:num_samples * 8), 8, num_samples).';
                %disp(bits_matrix)
                

                % Convert the bits back to decimal values
                decimal_values = bi2de(bits_matrix, 'left-msb');
                disp(class(decimal_values));
                disp(class(max(abs(app.recordedAudioSignal))))
                disp((max(abs(app.recordedAudioSignal))))
                decimal_values = decimal_values*(double(max(abs(app.recordedAudioSignal))));
                disp(decimal_values);
                %disp(app.recordedAudioSignal);

                % Reshape the decimal values into the original audio sample size
                
                reconstructed_audio = typecast(uint8(decimal_values), 'single');

                % Normalize the reconstructed audio
                reconstructed_audio = reconstructed_audio / max(abs(reconstructed_audio));
                disp(reconstructed_audio);

                % Play the reconstructed audio
                soundsc(reconstructed_audio, app.Fs);

                % Display the reconstructed audio signal in UIAxes
                t = (0:length(reconstructed_audio)-1) / app.Fs;
                plot(app.UIAxes, t, reconstructed_audio);
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Amplitude');
                title(app.UIAxes, 'Reconstructed Audio Signal');


            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RandomBitsButton = uibutton(app.LeftPanel, 'push');
            app.RandomBitsButton.ButtonPushedFcn = createCallbackFcn(app, @RandomBitsButtonPushed, true);
            app.RandomBitsButton.Text = 'Random Bits';
            app.TexttoBitsButton = uibutton(app.LeftPanel, 'push');
            app.TexttoBitsButton.ButtonPushedFcn = createCallbackFcn(app, @TexttoBitsButtonPushed, true);
            app.TexttoBitsButton.Text = 'Text to Bits';
            app.ImagetoBitsButton = uibutton(app.LeftPanel, 'push');
            app.ImagetoBitsButton.ButtonPushedFcn = createCallbackFcn(app, @ImagetoBitsButtonPushed, true);
            app.ImagetoBitsButton.Text = 'Image to Bits';
            app.AudiotoBitsButton = uibutton(app.LeftPanel, 'push');
            app.AudiotoBitsButton.ButtonPushedFcn = createCallbackFcn(app, @AudiotoBitsButtonPushed, true);
            app.AudiotoBitsButton.Text = 'Audio to Bits';
            app.OutputsTextAreaLabel = uilabel(app.LeftPanel);
            app.OutputsTextAreaLabel.Text = 'Outputs';
            app.OutputsTextArea = uitextarea(app.LeftPanel);
            app.ClearResetButton = uibutton(app.LeftPanel, 'push');
            app.ClearResetButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResetButtonPushed, true);
            app.ClearResetButton.Text = 'Clear / Reset';
            app.SourceCodesLabel = uilabel(app.LeftPanel);
            app.SourceCodesLabel.Text = 'Source Codes';
            app.RecordingLamp = uilamp(app.LeftPanel);
            app.OutputsTextArea_2 = uitextarea(app.LeftPanel);
            app.RecordButton = uibutton(app.LeftPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.CenterPanel);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.CenterPanel);
            zlabel(app.UIAxes_4, 'Z')
            app.DemodulateButton = uibutton(app.CenterPanel, 'push');
            app.DemodulateButton.ButtonPushedFcn = createCallbackFcn(app, @DemodulateButtonPushed, true);
            app.DemodulateButton.Text = 'Demodulate';
            app.BitErrorRateButton = uibutton(app.CenterPanel, 'push');
            app.BitErrorRateButton.ButtonPushedFcn = createCallbackFcn(app, @BitErrorRateButtonPushed, true);
            app.BitErrorRateButton.Text = 'Bit Error Rate';
            app.ReconstructButton = uibutton(app.CenterPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Visualize')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'png-clipart-power-on-and-off-logo-red-power-button-electronics-power-buttons-thumbnail.png';
            app.Button.Text = '';
            app.ChannelCodingLabel = uilabel(app.RightPanel);
            app.ChannelCodingLabel.Text = 'Channel Coding';
            app.HammingButton = uibutton(app.RightPanel, 'push');
            app.HammingButton.ButtonPushedFcn = createCallbackFcn(app, @HammingButtonPushed, true);
            app.HammingButton.Text = '(7,4) Hamming';
            app.BCH12764Button = uibutton(app.RightPanel, 'push');
            app.BCH12764Button.ButtonPushedFcn = createCallbackFcn(app, @BCH12764ButtonPushed, true);
            app.BCH12764Button.Text = 'BCH (127,64)';
            app.BCH6351Button = uibutton(app.RightPanel, 'push');
            app.BCH6351Button.ButtonPushedFcn = createCallbackFcn(app, @BCH6351ButtonPushed, true);
            app.BCH6351Button.Text = 'BCH (63,51)';
            app.ChannelCodingmaytakeawhileLabel = uilabel(app.RightPanel);
            app.ChannelCodingmaytakeawhileLabel.Text = 'Channel Coding may take a while!';
            app.FSKButton = uibutton(app.RightPanel, 'push');
            app.FSKButton.ButtonPushedFcn = createCallbackFcn(app, @FSKButtonPushed, true);
            app.FSKButton.Text = '16 FSK';
            app.QAMButton = uibutton(app.RightPanel, 'push');
            app.QAMButton.ButtonPushedFcn = createCallbackFcn(app, @QAMButtonPushed, true);
            app.QAMButton.Text = '16 QAM';
            app.FSKButton_3 = uibutton(app.RightPanel, 'push');
            app.FSKButton_3.ButtonPushedFcn = createCallbackFcn(app, @FSKButton_3Pushed, true);
            app.FSKButton_3.Text = '64 FSK';
            app.QAMButton_2 = uibutton(app.RightPanel, 'push');
            app.QAMButton_2.ButtonPushedFcn = createCallbackFcn(app, @QAMButton_2Pushed, true);
            app.QAMButton_2.Text = '64 QAM';
            app.SNRDBSliderLabel = uilabel(app.RightPanel);
            app.SNRDBSliderLabel.Text = 'SNR (DB)';
            app.SNRDBSlider = uislider(app.RightPanel);
            app.SNRDBSlider.Limits = [0 50];
            app.SNRDBSlider.ValueChangedFcn = createCallbackFcn(app, @SNRDBSliderValueChanged, true);
            app.AddAWGNButton = uibutton(app.RightPanel, 'push');
            app.AddAWGNButton.ButtonPushedFcn = createCallbackFcn(app, @AddAWGNButtonPushed, true);
            app.AddAWGNButton.Text = 'Add AWGN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIG_COM_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end